classdef RVH_PF1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CT2                           matlab.ui.control.NumericEditField
        ConsumoTotal12EditFieldLabel  matlab.ui.control.Label
        CT1                           matlab.ui.control.NumericEditField
        ConsumoTotal1EditFieldLabel   matlab.ui.control.Label
        BI                            matlab.ui.control.NumericEditField
        BIEditFieldLabel              matlab.ui.control.Label
        escenario                     matlab.ui.control.DropDown
        EscenarioDropDownLabel        matlab.ui.control.Label
        pesogr                        matlab.ui.control.NumericEditField
        PesogrLabel                   matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        Modelo1Tab                    matlab.ui.container.Tab
        CPR                           matlab.ui.control.NumericEditField
        ConsumoEditFieldLabel         matlab.ui.control.Label
        Tabla                         matlab.ui.control.Table
        GRAFICAButton                 matlab.ui.control.Button
        BM1                           matlab.ui.control.NumericEditField
        ConsumoTotalLabel             matlab.ui.control.Label
        preM1                         matlab.ui.control.Button
        GM1                           matlab.ui.control.UIAxes
        Modelo2Tab                    matlab.ui.container.Tab
        Tabla2                        matlab.ui.control.Table
        Garf2                         matlab.ui.control.Button
        CPR2                          matlab.ui.control.NumericEditField
        ConsumoEditFieldLabel_2       matlab.ui.control.Label
        preM2                         matlab.ui.control.Button
        GM2                           matlab.ui.control.UIAxes
        accion                        matlab.ui.control.DropDown
        AccionLabel                   matlab.ui.control.Label
        distancia                     matlab.ui.control.NumericEditField
        DistanciamLabel               matlab.ui.control.Label
        altura                        matlab.ui.control.NumericEditField
        AlturamLabel                  matlab.ui.control.Label
        ParmetrosLabel                matlab.ui.control.Label
        time                          matlab.ui.control.NumericEditField
        TiemposegLabel                matlab.ui.control.Label
        PROYECTOFINALAnlisisdeautonomadelUAVconcargaLabel  matlab.ui.control.Label
        REDESVEHICULARESYHETEROGNEALabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function mod1Pushed(app, event)
          load('RLM1.mat');  % Asegúrate de que este es el nombre correcto del archivo
          disp('Modelo cargado correctamente');
          p1 = app.time.Value;
          p2 = app.altura.Value;
          p3 = app.distancia.Value;
          if app.accion.Value == 'Interior'
                p4 = 0;
          else 
                p4 = 1;
          end
          p5 = app.pesogr.Value;
          newdata = [p5, p1, p2, p3, p4 ];
          T = array2table(newdata, 'VariableNames', {'Peso', 'Timemin', 'D_V','D_H','Entorno'})
          battery = RLM1.predictFcn(T)
          app.bateria.Value  = battery;

        end

        % Value changed function: accion
        function accionValueChanged(app, event)
            value = app.accion.Value;
        end

        % Button pushed function: preM1
        function preM1ButtonPushed(app, event)
          load('M1LIR.mat');  % Asegúrate de que este es el nombre correcto del archivo
          disp('Modelo cargado correctamente');
          % VALORES DE PREDICCION
          p1 = app.time.Value;
          p2 = app.altura.Value;
          p3 = app.distancia.Value;
          p5 = app.pesogr.Value;
          p7 = app.BI.Value;
          
%           p4 = app.accion.Value;
          
        switch app.accion.Value
            case 'Subir'
                p4 = 1;
            case 'Lateral'
                p4 = 2;
            case 'Bajar'
                p4 = 3;
            case 'Mantener'
                p4 = 4;
            otherwise
                error('Valor no reconocido')
        end
        
          if app.escenario.Value == 'Escenario 1'
                p6 = 1;
          elseif app.escenario.Value == 'Escenario 2' 
                p6 = 2;
          elseif  app.escenario.Value == 'Escenario 3'  
                p6 = 3;
          elseif  app.escenario.Value == 'Escenario 4' 
                p6 = 4;
          end

          newdata = [p1,p5,p2,p3,p4,p7]
          T = array2table(newdata, 'VariableNames', {'Tiempo', 'Peso','Altura','Distancia', 'Accion','BI'})
          %T = array2table(newdata, 'VariableNames', {'Tiempo', 'Peso','Altura','Distancia'})
          battery = M1LIR.predictFcn(T)
          app.CPR.Value  = battery;
          
          % Agregar la predicción a los datos
          newdata_with_pred = [newdata, battery];
          newRow = array2table(newdata_with_pred, 'VariableNames', {'Tiempo', 'Peso', 'Altura', 'Distancia','Accion','BI', 'Prediccion'});
          app.Tabla.Data = [app.Tabla.Data; newRow];
          
          app.BM1.Value = app.BM1.Value  + battery;
          app.CT1.Value = 100 - battery;

        end

        % Button pushed function: GRAFICAButton
        function GRAFICAButtonPushed(app, event)
          load('M1LIR.mat');  % Asegúrate de que este es el nombre correcto del archivo
          disp('Modelo cargado correctamente');
          time_values = 0:1:app.time.Value; % Vector de valores del tiempo
          battery_predictions = zeros(size(time_values)); % Vector de valores de predicción de batería
          p2 = app.altura.Value;
          p3 = app.distancia.Value;
          p5 = app.pesogr.Value;
          p7 = app.BI.Value;
          
          %p4 = app.accion.Value;
          
        switch app.accion.Value
            case 'Subir'
                p4 = 1;
            case 'Lateral'
                p4 = 2;
            case 'Bajar'
                p4 = 3;
            case 'Mantener'
                p4 = 4;
                   
            otherwise
                error('Valor no reconocido')
        end
        
          if app.escenario.Value == 'Escenario 1'
                p6 = 1;
          elseif app.escenario.Value == 'Escenario 2' 
                p6 = 2;
          elseif  app.escenario.Value == 'Escenario 3'  
                p6 = 3;
          elseif  app.escenario.Value == 'Escenario 4' 
                p6 = 4;
          end
          
          for i = 1:length(time_values)
            disp('Valor del i');
            disp(i);
            disp('Valor del p1');
            p1 = time_values(i) 
%             newdata1 = [p6,p1,p5,p2,p3,p4]
%             T1 = array2table(newdata1, 'VariableNames', {'Escenario', 'Tiempo', 'Peso','Altura','Distancia', 'Accion'})
            newdata1 = [p1,p5,p2,p3,p4,p7]
            T1 = array2table(newdata1, 'VariableNames', {'Tiempo', 'Peso','Altura','Distancia', 'Accion','BI'})
            battery_predictions(i) = M1LIR.predictFcn(T1);
          end
          
          %plot(app.GM1,time_values,battery_predictions);
            %plot(app.GM1, time_values, battery_predictions, 'LineWidth', 2, 'Color', 'b');
            scatter(app.GM1, time_values, battery_predictions, 'filled');
            hold(app.GM1, 'on');
            
            % Resaltar el último valor predicho
            %last_time = app.time.Value;
            %last_prediction = app.bateria.Value ;
            last_time = time_values(end);
            last_prediction = battery_predictions(end);
            scatter(app.GM1, last_time, last_prediction, 100, 'r', 'filled');  % Punto rojo más grande
            text(app.GM1, last_time, last_prediction, sprintf(' %.2f', last_prediction), 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right', 'FontSize', 12, 'FontWeight', 'bold', 'Color', 'r');
            
            title(app.GM1, 'Predicción de la Batería en Función del Tiempo', 'FontSize', 14, 'FontWeight', 'bold');
            xlabel(app.GM1, 'Tiempo (seg)', 'FontSize', 12, 'FontWeight', 'bold');
            ylabel(app.GM1, 'Predicción de la Batería', 'FontSize', 12, 'FontWeight', 'bold');
            grid(app.GM1, 'on');
        
            % Mejorar la apariencia de los ejes
            set(app.GM1, 'XGrid', 'on', 'YGrid', 'on', 'GridLineStyle', '--', 'GridAlpha', 0.6);
            set(app.GM1, 'Box', 'on', 'LineWidth', 1.5);
            legend(app.GM1, {'Predicción de Batería'}, 'Location', 'best', 'FontSize', 10);
        end

        % Cell edit callback: Tabla
        function TablaCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
        end

        % Cell selection callback: Tabla
        function TablaCellSelection(app, event)
            indices = event.Indices;
            
        end

        % Button pushed function: preM2
        function preM2ButtonPushed(app, event)
          load('M2LMT.mat');  % Asegúrate de que este es el nombre correcto del archivo
          disp('Modelo cargado correctamente');
          % VALORES DE PREDICCION
          p1 = app.time.Value;
          p2 = app.altura.Value;
          p3 = app.distancia.Value;
          p5 = app.pesogr.Value;
          
        switch app.accion.Value
            case 'Subir'
                p4 = 1;
            case 'Lateral'
                p4 = 2;
            case 'Bajar'
                p4 = 3;
            case 'Mantener'
                p4 = 4;
            otherwise
                error('Valor no reconocido')
        end
        
          if app.escenario.Value == 'Escenario 1'
                p6 = 1;
          elseif app.escenario.Value == 'Escenario 2' 
                p6 = 2;
          elseif  app.escenario.Value == 'Escenario 3'  
                p6 = 3;
          elseif  app.escenario.Value == 'Escenario 4' 
                p6 = 4;
          end
          
          p7 = app.BI.Value;

          newdata = [p1,p5,p2,p3,p4,p7]
          T = array2table(newdata, 'VariableNames', {'Tiempo', 'Peso','Altura','Distancia','Accion','BI'})
          battery = M2LMT.predictFcn(T)
          app.CPR2.Value  = battery;
          
          % Agregar la predicción a los datos
          newdata_with_pred = [newdata, battery];
          newRow = array2table(newdata_with_pred, 'VariableNames', {'Tiempo', 'Peso', 'Altura', 'Distancia','Accion','BI', 'Prediccion'});
          app.Tabla2.Data = [app.Tabla2.Data; newRow];
          
          app.BM2.Value = app.BM2.Value  + battery;
          app.CT2.Value = 100 - battery;


        end

        % Button pushed function: Garf2
        function Garf2ButtonPushed(app, event)
          load('M2LMT.mat');  % Asegúrate de que este es el nombre correcto del archivo
          disp('Modelo cargado correctamente');
          time_values = 0:1:app.time.Value; % Vector de valores del tiempo
          battery_predictions = zeros(size(time_values)); % Vector de valores de predicción de batería
          p2 = app.altura.Value;
          p3 = app.distancia.Value;
          p5 = app.pesogr.Value;
          p7 = app.BI.Value;
          
        switch app.accion.Value
            case 'Subir'
                p4 = 1;
            case 'Lateral'
                p4 = 2;
            case 'Bajar'
                p4 = 3;
            case 'Mantener'
                p4 = 4;
            otherwise
                error('Valor no reconocido')
        end
        
          if app.escenario.Value == 'Escenario 1'
                p6 = 1;
          elseif app.escenario.Value == 'Escenario 2' 
                p6 = 2;
          elseif  app.escenario.Value == 'Escenario 3'  
                p6 = 3;
          elseif  app.escenario.Value == 'Escenario 4' 
                p6 = 4;
          end
          
          for i = 1:length(time_values)
            disp('Valor del i');
            disp(i);
            disp('Valor del p1');
            p1 = time_values(i)
            
%             newdata1 = [p1,p5,p2,p3,p4]
%             T1 = array2table(newdata1, 'VariableNames', {'Tiempo', 'Peso','Altura','Distancia', 'Accion'})
            newdata1 = [p1,p5,p2,p3,p4,p7]
            T1 = array2table(newdata1, 'VariableNames', {'Tiempo', 'Peso','Altura','Distancia', 'Accion','BI'})
            battery_predictions(i) = M2LMT.predictFcn(T1);
          end
          
          %plot(app.GM1,time_values,battery_predictions);
            %plot(app.GM1, time_values, battery_predictions, 'LineWidth', 2, 'Color', 'b');
            scatter(app.GM2, time_values, battery_predictions, 'filled');
            hold(app.GM2, 'on');
            
            % Resaltar el último valor predicho
            %last_time = app.time.Value;
            %last_prediction = app.bateria.Value ;
            last_time = time_values(end);
            last_prediction = battery_predictions(end);
            scatter(app.GM2, last_time, last_prediction, 100, 'r', 'filled');  % Punto rojo más grande
            text(app.GM2, last_time, last_prediction, sprintf(' %.2f', last_prediction), 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right', 'FontSize', 12, 'FontWeight', 'bold', 'Color', 'r');
            
            title(app.GM2, 'Predicción de la Batería en Función del Tiempo', 'FontSize', 14, 'FontWeight', 'bold');
            xlabel(app.GM2, 'Tiempo (seg)', 'FontSize', 12, 'FontWeight', 'bold');
            ylabel(app.GM2, 'Predicción de la Batería', 'FontSize', 12, 'FontWeight', 'bold');
            grid(app.GM2, 'on');
        
            % Mejorar la apariencia de los ejes
            set(app.GM2, 'XGrid', 'on', 'YGrid', 'on', 'GridLineStyle', '--', 'GridAlpha', 0.6);
            set(app.GM2, 'Box', 'on', 'LineWidth', 1.5);
            legend(app.GM2, {'Predicción de Batería'}, 'Location', 'best', 'FontSize', 10);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo.png';
            app.REDESVEHICULARESYHETEROGNEALabel = uilabel(app.UIFigure);
            app.REDESVEHICULARESYHETEROGNEALabel.Text = 'REDES VEHICULARES Y HETEROGÉNEA';
            app.PROYECTOFINALAnlisisdeautonomadelUAVconcargaLabel = uilabel(app.UIFigure);
            app.PROYECTOFINALAnlisisdeautonomadelUAVconcargaLabel.Text = {'PROYECTO FINAL'; ' Análisis de autonomía del UAV con carga'};
            app.TiemposegLabel = uilabel(app.UIFigure);
            app.TiemposegLabel.Text = 'Tiempo (seg)';
            app.time = uieditfield(app.UIFigure, 'numeric');
            app.ParmetrosLabel = uilabel(app.UIFigure);
            app.ParmetrosLabel.Text = 'Parámetros';
            app.AlturamLabel = uilabel(app.UIFigure);
            app.AlturamLabel.Text = 'Altura (m)';
            app.altura = uieditfield(app.UIFigure, 'numeric');
            app.DistanciamLabel = uilabel(app.UIFigure);
            app.DistanciamLabel.Text = 'Distancia (m)';
            app.distancia = uieditfield(app.UIFigure, 'numeric');
            app.AccionLabel = uilabel(app.UIFigure);
            app.AccionLabel.Text = 'Accion';
            app.accion = uidropdown(app.UIFigure);
            app.accion.Items = {'Subir', 'Lateral', 'Bajar', 'Mantener'};
            app.accion.ValueChangedFcn = createCallbackFcn(app, @accionValueChanged, true);
            app.accion.Value = 'Subir';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Modelo1Tab = uitab(app.TabGroup);
            app.Modelo1Tab.Title = 'Modelo 1';
            app.GM1 = uiaxes(app.Modelo1Tab);
            title(app.GM1, 'Title')
            xlabel(app.GM1, 'X')
            ylabel(app.GM1, 'Y')
            zlabel(app.GM1, 'Z')
            app.preM1 = uibutton(app.Modelo1Tab, 'push');
            app.preM1.ButtonPushedFcn = createCallbackFcn(app, @preM1ButtonPushed, true);
            app.preM1.Text = 'PREDICCION';
            app.ConsumoTotalLabel = uilabel(app.Modelo1Tab);
            app.ConsumoTotalLabel.Text = 'Consumo Total';
            app.BM1 = uieditfield(app.Modelo1Tab, 'numeric');
            app.GRAFICAButton = uibutton(app.Modelo1Tab, 'push');
            app.GRAFICAButton.ButtonPushedFcn = createCallbackFcn(app, @GRAFICAButtonPushed, true);
            app.GRAFICAButton.Text = 'GRAFICA ';
            app.Tabla = uitable(app.Modelo1Tab);
            app.Tabla.ColumnName = {'Escenario'; 'Tiempo (s)'; 'Peso (gr)'; 'Altura (m)'; 'Distancia (m)'; 'Acción'; 'Batería'};
            app.Tabla.RowName = {};
            app.Tabla.CellEditCallback = createCallbackFcn(app, @TablaCellEdit, true);
            app.Tabla.CellSelectionCallback = createCallbackFcn(app, @TablaCellSelection, true);
            app.ConsumoEditFieldLabel = uilabel(app.Modelo1Tab);
            app.ConsumoEditFieldLabel.Text = 'Consumo ';
            app.CPR = uieditfield(app.Modelo1Tab, 'numeric');
            app.Modelo2Tab = uitab(app.TabGroup);
            app.Modelo2Tab.Title = 'Modelo 2';
            app.GM2 = uiaxes(app.Modelo2Tab);
            title(app.GM2, 'Title')
            xlabel(app.GM2, 'X')
            ylabel(app.GM2, 'Y')
            zlabel(app.GM2, 'Z')
            app.preM2 = uibutton(app.Modelo2Tab, 'push');
            app.preM2.ButtonPushedFcn = createCallbackFcn(app, @preM2ButtonPushed, true);
            app.preM2.Text = 'PREDICCION';
            app.ConsumoEditFieldLabel_2 = uilabel(app.Modelo2Tab);
            app.ConsumoEditFieldLabel_2.Text = 'Consumo';
            app.CPR2 = uieditfield(app.Modelo2Tab, 'numeric');
            app.Garf2 = uibutton(app.Modelo2Tab, 'push');
            app.Garf2.ButtonPushedFcn = createCallbackFcn(app, @Garf2ButtonPushed, true);
            app.Garf2.Text = 'GRAFICA';
            app.Tabla2 = uitable(app.Modelo2Tab);
            app.Tabla2.ColumnName = {'Escenario'; 'Tiempo (s)'; 'Peso (gr)'; 'Altura (m)'; 'Distancia (m)'; 'Acción'; 'Bateria'};
            app.Tabla2.RowName = {};
            app.PesogrLabel = uilabel(app.UIFigure);
            app.PesogrLabel.Text = 'Peso (gr)';
            app.pesogr = uieditfield(app.UIFigure, 'numeric');
            app.EscenarioDropDownLabel = uilabel(app.UIFigure);
            app.EscenarioDropDownLabel.Text = 'Escenario';
            app.escenario = uidropdown(app.UIFigure);
            app.escenario.Items = {'Escenario 1', 'Escenario 2', 'Escenario 3', 'Escenario 4'};
            app.escenario.Value = 'Escenario 1';
            app.BIEditFieldLabel = uilabel(app.UIFigure);
            app.BIEditFieldLabel.Text = 'BI';
            app.BI = uieditfield(app.UIFigure, 'numeric');
            app.ConsumoTotal1EditFieldLabel = uilabel(app.UIFigure);
            app.ConsumoTotal1EditFieldLabel.Text = 'Consumo Total1';
            app.CT1 = uieditfield(app.UIFigure, 'numeric');
            app.ConsumoTotal12EditFieldLabel = uilabel(app.UIFigure);
            app.ConsumoTotal12EditFieldLabel.Text = 'Consumo Total12';
            app.CT2 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RVH_PF1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end